classdef PF1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        EscalarPETEditField       matlab.ui.control.NumericEditField
        EscalarPETEditFieldLabel  matlab.ui.control.Label
        VerPETButton              matlab.ui.control.Button
        VerMRIButton              matlab.ui.control.Button
        FusinPETMRIButton         matlab.ui.control.Button
        CargarPETButton           matlab.ui.control.Button
        TransparenciaPETSlider    matlab.ui.control.Slider
        TransparenciaPETLabel     matlab.ui.control.Label
        CargarMRIButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarMRIButton
        function CargarMRIButtonPushed(app, event)
            global imagen1
            [filename, pathname] = uigetfile({'*.jpg'},'File Selector');
            fp1 = strcat(pathname,filename);
            imagen1 = imread(fp1);
        end

        % Button pushed function: CargarPETButton
        function CargarPETButtonPushed(app, event)
            global imagen2
            [filename, pathname] = uigetfile({'*.jpg'},'File Selector');
            fp2 = strcat(pathname,filename);
            imagen2 = imread(fp2);
        end

        % Button pushed function: FusinPETMRIButton
        function FusinPETMRIButtonPushed(app, event)
            global imagen1
            global imagen2
            global imagen3
            global x
            global y
            global E
            global k
            transparencia = k;
            imagen2_t = im2uint8(imadjust(imagen2, [0 1], [], transparencia));
            imagen2_r = imresize(imagen2_t,E);
            imagen2_m = imtranslate(imagen2_r,[x,-y]);
            imagen3 = imfuse(imagen1,imagen2_m,"blend");
            imshow(imagen3, 'Parent', app.UIAxes);
        end

        % Value changed function: TransparenciaPETSlider
        function TransparenciaPETSliderValueChanged(app, event)
            global k
            value = app.TransparenciaPETSlider.Value;
            k = value;
        end

        % Button pushed function: VerMRIButton
        function VerMRIButtonPushed(app, event)
            global imagen1
            imshow(imagen1,'Parent',app.UIAxes);
        end

        % Button pushed function: VerPETButton
        function VerPETButtonPushed(app, event)
            global imagen2
            imshow(imagen2,'Parent',app.UIAxes);
        end

        % Callback function
        function GuardarimagenButtonPushed(app, event)

        end

        % Callback function
        function NombredelaimagenEditFieldValueChanged(app, event)

        end

        % Value changed function: EscalarPETEditField
        function EscalarPETEditFieldValueChanged(app, event)
            global E
            value = app.EscalarPETEditField.Value;
            E = value;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            global x
            value = app.XSlider.Value;
            x = value;
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            global y
            value = app.YSlider.Value;
            y = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CargarMRIButton = uibutton(app.UIFigure, 'push');
            app.CargarMRIButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMRIButtonPushed, true);
            app.CargarMRIButton.Text = 'Cargar MRI';
            app.TransparenciaPETLabel = uilabel(app.UIFigure);
            app.TransparenciaPETLabel.Text = {'Transparencia '; 'PET'};
            app.TransparenciaPETSlider = uislider(app.UIFigure);
            app.TransparenciaPETSlider.Limits = [0 5];
            app.TransparenciaPETSlider.ValueChangedFcn = createCallbackFcn(app, @TransparenciaPETSliderValueChanged, true);
            app.TransparenciaPETSlider.MinorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.CargarPETButton = uibutton(app.UIFigure, 'push');
            app.CargarPETButton.ButtonPushedFcn = createCallbackFcn(app, @CargarPETButtonPushed, true);
            app.CargarPETButton.Text = 'Cargar PET';
            app.FusinPETMRIButton = uibutton(app.UIFigure, 'push');
            app.FusinPETMRIButton.ButtonPushedFcn = createCallbackFcn(app, @FusinPETMRIButtonPushed, true);
            app.FusinPETMRIButton.Text = 'Fusión PET/MRI';
            app.VerMRIButton = uibutton(app.UIFigure, 'push');
            app.VerMRIButton.ButtonPushedFcn = createCallbackFcn(app, @VerMRIButtonPushed, true);
            app.VerMRIButton.Text = 'Ver MRI';
            app.VerPETButton = uibutton(app.UIFigure, 'push');
            app.VerPETButton.ButtonPushedFcn = createCallbackFcn(app, @VerPETButtonPushed, true);
            app.VerPETButton.Text = 'Ver PET';
            app.EscalarPETEditFieldLabel = uilabel(app.UIFigure);
            app.EscalarPETEditFieldLabel.Text = 'Escalar PET';
            app.EscalarPETEditField = uieditfield(app.UIFigure, 'numeric');
            app.EscalarPETEditField.ValueChangedFcn = createCallbackFcn(app, @EscalarPETEditFieldValueChanged, true);
            app.EscalarPETEditField.Value = 1;
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-100 100];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Value = 1;
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-100 100];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end