classdef fusion_proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VerPETButton                 matlab.ui.control.Button
        VerMRIButton                 matlab.ui.control.Button
        FusinPETMRIButton            matlab.ui.control.Button
        CargarPETButton              matlab.ui.control.Button
        TransparenciaPETSlider       matlab.ui.control.Slider
        TransparenciaPETSliderLabel  matlab.ui.control.Label
        CargarMRIButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarMRIButton
        function CargarMRIButtonPushed(app, event)
            global imagen1
            [filename, pathname] = uigetfile({'*.jpg'},'File Selector');
            fp1 = strcat(pathname,filename);
            imagen1 = imread(fp1);
        end

        % Button pushed function: CargarPETButton
        function CargarPETButtonPushed(app, event)
            global imagen2
            [filename, pathname] = uigetfile({'*.jpg'},'File Selector');
            fp2 = strcat(pathname,filename);
            imagen2 = imread(fp2);
        end

        % Button pushed function: FusinPETMRIButton
        function FusinPETMRIButtonPushed(app, event)
            global imagen1
            global imagen2
            global k
            imagen3 = imfuse(imagen1,imagen2)
            imshow(imagen3,'Parent',app.UIAxes);
        end

        % Value changed function: TransparenciaPETSlider
        function TransparenciaPETSliderValueChanged(app, event)
            global k
            value = app.TransparenciaPETSlider.Value;
            k = value
        end

        % Button pushed function: VerMRIButton
        function VerMRIButtonPushed(app, event)
            global imagen1
            imshow(imagen1,'Parent',app.UIAxes);
        end

        % Button pushed function: VerPETButton
        function VerPETButtonPushed(app, event)
            global imagen2
            imshow(imagen2,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagen')
            app.CargarMRIButton = uibutton(app.UIFigure, 'push');
            app.CargarMRIButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMRIButtonPushed, true);
            app.CargarMRIButton.Text = 'Cargar MRI';
            app.TransparenciaPETSliderLabel = uilabel(app.UIFigure);
            app.TransparenciaPETSliderLabel.Text = 'Transparencia PET';
            app.TransparenciaPETSlider = uislider(app.UIFigure);
            app.TransparenciaPETSlider.Limits = [0 1];
            app.TransparenciaPETSlider.ValueChangedFcn = createCallbackFcn(app, @TransparenciaPETSliderValueChanged, true);
            app.CargarPETButton = uibutton(app.UIFigure, 'push');
            app.CargarPETButton.ButtonPushedFcn = createCallbackFcn(app, @CargarPETButtonPushed, true);
            app.CargarPETButton.Text = 'Cargar PET';
            app.FusinPETMRIButton = uibutton(app.UIFigure, 'push');
            app.FusinPETMRIButton.ButtonPushedFcn = createCallbackFcn(app, @FusinPETMRIButtonPushed, true);
            app.FusinPETMRIButton.Text = 'Fusión PET/MRI';
            app.VerMRIButton = uibutton(app.UIFigure, 'push');
            app.VerMRIButton.ButtonPushedFcn = createCallbackFcn(app, @VerMRIButtonPushed, true);
            app.VerMRIButton.Text = 'Ver MRI';
            app.VerPETButton = uibutton(app.UIFigure, 'push');
            app.VerPETButton.ButtonPushedFcn = createCallbackFcn(app, @VerPETButtonPushed, true);
            app.VerPETButton.Text = 'Ver PET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fusion_proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end